classdef z < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadImageButton            matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        LowPassFilteringTab        matlab.ui.container.Tab
        LPFNValue                  matlab.ui.control.NumericEditField
        NEditFieldLabel            matlab.ui.control.Label
        LPFD0Value                 matlab.ui.control.NumericEditField
        D0EditFieldLabel           matlab.ui.control.Label
        LPFType                    matlab.ui.control.DropDown
        TypeDropDownLabel          matlab.ui.control.Label
        LPFSpectrum                matlab.ui.control.UIAxes
        LPFFilter                  matlab.ui.control.UIAxes
        HighPassFilteringTab       matlab.ui.container.Tab
        HPFNValue                  matlab.ui.control.NumericEditField
        NEditField_2Label          matlab.ui.control.Label
        HPFD0Value                 matlab.ui.control.NumericEditField
        D0EditField_2Label         matlab.ui.control.Label
        HPFType                    matlab.ui.control.DropDown
        TypeDropDownLabel_2        matlab.ui.control.Label
        HPFSpectrum                matlab.ui.control.UIAxes
        HPFFilter                  matlab.ui.control.UIAxes
        EDITTHISTab                matlab.ui.container.Tab
        PeriodicNoiseFilteringTab  matlab.ui.container.Tab
        NoiseFilterFourierAfter    matlab.ui.control.UIAxes
        NoiseFilterImage           matlab.ui.control.UIAxes
        NoiseFilterFourierBefore   matlab.ui.control.UIAxes
        OutputImage                matlab.ui.control.UIAxes
        InputImage                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        input_image
        filter_mode
    end
    
    methods (Access = private)
        function updateimage(app, fname)
            try
                app.input_image = imread(fname);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            displayImages(app)
        end

        function displayImages(app)
            if strcmp(app.filter_mode.name, 'low pass filtering')
                if strcmp(app.filter_mode.type, 'ideal')
                    lpfNVisibility(app, 'off');
                    [image_out, filter, spectrum] = ilpf(app.input_image, app.LPFD0Value.Value);
                elseif strcmp(app.filter_mode.type, 'gaussian')
                    lpfNVisibility(app, 'off');
                    [image_out, filter, spectrum] = glpf(app.input_image, app.LPFD0Value.Value);
                elseif strcmp(app.filter_mode.type, 'butterworth')
                    lpfNVisibility(app, 'on');
                    [image_out, filter, spectrum] = blpf(app.input_image, app.LPFD0Value.Value, app.LPFNValue.Value);
                end
                imagesc(app.InputImage, app.input_image);
                imagesc(app.LPFSpectrum, spectrum);
                imagesc(app.LPFFilter, filter);
                imagesc(app.OutputImage, image_out);
            elseif strcmp(app.filter_mode.name, 'high pass filtering')
                if strcmp(app.filter_mode.type, 'ideal')
                    hpfNVisibility(app, 'off');
                    [image_out, filter, spectrum] = ihpf(app.input_image, app.HPFD0Value.Value);
                elseif strcmp(app.filter_mode.type, 'gaussian')
                    hpfNVisibility(app, 'off');
                    [image_out, filter, spectrum] = ghpf(app.input_image, app.HPFD0Value.Value);
                elseif strcmp(app.filter_mode.type, 'butterworth')
                    hpfNVisibility(app, 'on');
                    [image_out, filter, spectrum] = bhpf(app.input_image, app.HPFD0Value.Value, app.HPFNValue.Value);
                end
                imagesc(app.InputImage, app.input_image);
                imagesc(app.HPFSpectrum, spectrum);
                imagesc(app.HPFFilter, filter);
                imagesc(app.OutputImage, image_out);
            elseif strcmp(app.filter_mode.name, 'periodic noise filtering')
                app.input_image = rgb2gray(app.input_image);
                [image_out, spectrum_i, filter, spectrum_f, minS, maxS] = filter_periodic_noise(app.input_image, 5.2);
                imagesc(app.InputImage, app.input_image);
                imagesc(app.NoiseFilterFourierBefore, spectrum_i);
                imagesc(app.NoiseFilterImage, filter);
                imagesc(app.NoiseFilterFourierAfter, spectrum_f);
                imagesc(app.OutputImage, image_out);
            end
        end

        function lpfNVisibility(app, visibility)
            app.LPFNValue.Visible = visibility;
            app.NEditFieldLabel.Visible = visibility;
        end

        function hpfNVisibility(app, visibility)
            app.HPFNValue.Visible = visibility;
            app.NEditField_2Label.Visible = visibility;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filter_mode.name = "low pass filtering";
            app.filter_mode.type = "ideal";
            lpfNVisibility(app, 'off');
            hpfNVisibility(app, 'off');

            app.InputImage.Colormap = gray(256);
            axis(app.InputImage, 'image');
            
            app.OutputImage.Colormap = gray(256);
            axis(app.OutputImage, 'image');
            app.LPFFilter.Colormap = gray(256);
            axis(app.LPFFilter, 'image');
            app.LPFSpectrum.Colormap = gray(256);
            axis(app.LPFSpectrum, 'image');

            app.HPFFilter.Colormap = gray(256);
            axis(app.HPFFilter, 'image');
            app.HPFSpectrum.Colormap = gray(256);
            axis(app.HPFSpectrum, 'image');

            app.NoiseFilterFourierBefore.Colormap = gray(256);
            axis(app.NoiseFilterFourierBefore, 'image');
            app.NoiseFilterImage.Colormap = gray(256);
            axis(app.NoiseFilterImage, 'image');
            app.NoiseFilterFourierAfter.Colormap = gray(256);
            axis(app.NoiseFilterFourierAfter, 'image');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: LPFType
        function LPFTypeValueChanged(app, event)
            app.filter_mode.type = lower(app.LPFType.Value);
            displayImages(app);
        end

        % Button down function: LowPassFilteringTab
        function LowPassFilteringTabButtonDown(app, event)
            app.filter_mode.name = 'low pass filtering';
            app.filter_mode.type = lower(app.LPFType.Value);
            displayImages(app);
        end

        % Button down function: HighPassFilteringTab
        function HighPassFilteringTabButtonDown(app, event)
            app.filter_mode.name = 'high pass filtering';
            app.filter_mode.type = lower(app.HPFType.Value);
            displayImages(app);
        end

        % Button down function: PeriodicNoiseFilteringTab
        function PeriodicNoiseFilteringTabButtonDown(app, event)
            app.filter_mode.name = 'periodic noise filtering';
        end

        % Value changed function: HPFType
        function HPFTypeValueChanged(app, event)
            app.filter_mode.type = lower(app.HPFType.Value);
            displayImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputImage = uiaxes(app.UIFigure);
            title(app.InputImage, 'Input Image')
            app.InputImage.XTick = [];
            app.InputImage.YTick = [];
            app.OutputImage = uiaxes(app.UIFigure);
            title(app.OutputImage, 'Output Image')
            app.OutputImage.XTick = [];
            app.OutputImage.XTickLabel = '';
            app.OutputImage.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LowPassFilteringTab = uitab(app.TabGroup);
            app.LowPassFilteringTab.Title = 'Low Pass Filtering';
            app.LowPassFilteringTab.ButtonDownFcn = createCallbackFcn(app, @LowPassFilteringTabButtonDown, true);
            app.LPFFilter = uiaxes(app.LowPassFilteringTab);
            title(app.LPFFilter, 'Filter')
            app.LPFFilter.XTick = [];
            app.LPFFilter.YTick = [];
            app.LPFSpectrum = uiaxes(app.LowPassFilteringTab);
            title(app.LPFSpectrum, 'Fourier Spectrum')
            app.LPFSpectrum.XTick = [];
            app.LPFSpectrum.YTick = [];
            app.TypeDropDownLabel = uilabel(app.LowPassFilteringTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.LPFType = uidropdown(app.LowPassFilteringTab);
            app.LPFType.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.LPFType.ValueChangedFcn = createCallbackFcn(app, @LPFTypeValueChanged, true);
            app.LPFType.Value = 'Ideal';
            app.D0EditFieldLabel = uilabel(app.LowPassFilteringTab);
            app.D0EditFieldLabel.Text = 'D0';
            app.LPFD0Value = uieditfield(app.LowPassFilteringTab, 'numeric');
            app.LPFD0Value.Value = 50;
            app.NEditFieldLabel = uilabel(app.LowPassFilteringTab);
            app.NEditFieldLabel.Text = 'N';
            app.LPFNValue = uieditfield(app.LowPassFilteringTab, 'numeric');
            app.LPFNValue.Value = 1;
            app.HighPassFilteringTab = uitab(app.TabGroup);
            app.HighPassFilteringTab.Title = 'High Pass Filtering';
            app.HighPassFilteringTab.ButtonDownFcn = createCallbackFcn(app, @HighPassFilteringTabButtonDown, true);
            app.HPFFilter = uiaxes(app.HighPassFilteringTab);
            title(app.HPFFilter, 'Filter')
            app.HPFFilter.XTick = [];
            app.HPFFilter.YTick = [];
            app.HPFSpectrum = uiaxes(app.HighPassFilteringTab);
            title(app.HPFSpectrum, 'Fourier Spectrum')
            app.HPFSpectrum.XTick = [];
            app.HPFSpectrum.YTick = [];
            app.TypeDropDownLabel_2 = uilabel(app.HighPassFilteringTab);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.HPFType = uidropdown(app.HighPassFilteringTab);
            app.HPFType.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.HPFType.ValueChangedFcn = createCallbackFcn(app, @HPFTypeValueChanged, true);
            app.HPFType.Value = 'Ideal';
            app.D0EditField_2Label = uilabel(app.HighPassFilteringTab);
            app.D0EditField_2Label.Text = 'D0';
            app.HPFD0Value = uieditfield(app.HighPassFilteringTab, 'numeric');
            app.HPFD0Value.Value = 50;
            app.NEditField_2Label = uilabel(app.HighPassFilteringTab);
            app.NEditField_2Label.Text = 'N';
            app.HPFNValue = uieditfield(app.HighPassFilteringTab, 'numeric');
            app.HPFNValue.Value = 1;
            app.EDITTHISTab = uitab(app.TabGroup);
            app.EDITTHISTab.Title = 'EDIT THIS';
            app.PeriodicNoiseFilteringTab = uitab(app.TabGroup);
            app.PeriodicNoiseFilteringTab.Title = 'Periodic Noise Filtering';
            app.PeriodicNoiseFilteringTab.ButtonDownFcn = createCallbackFcn(app, @PeriodicNoiseFilteringTabButtonDown, true);
            app.NoiseFilterFourierBefore = uiaxes(app.PeriodicNoiseFilteringTab);
            title(app.NoiseFilterFourierBefore, 'Fourier Spectrum Before')
            app.NoiseFilterFourierBefore.XTick = [];
            app.NoiseFilterFourierBefore.YTick = [];
            app.NoiseFilterImage = uiaxes(app.PeriodicNoiseFilteringTab);
            title(app.NoiseFilterImage, 'Filter')
            app.NoiseFilterImage.XTick = [];
            app.NoiseFilterImage.YTick = [];
            app.NoiseFilterFourierAfter = uiaxes(app.PeriodicNoiseFilteringTab);
            title(app.NoiseFilterFourierAfter, 'Fourier Spectrum After')
            app.NoiseFilterFourierAfter.XTick = [];
            app.NoiseFilterFourierAfter.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = z

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end